classdef StabilityMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        RevertMenu                      matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        RunMenu_2                       matlab.ui.container.Menu
        ParametersforRateEquationsPanel  matlab.ui.container.Panel
        NameLabel                       matlab.ui.control.Label
        ValueLabel                      matlab.ui.control.Label
        X_label                         matlab.ui.control.Label
        Y_label                         matlab.ui.control.Label
        alphaEditFieldLabel             matlab.ui.control.Label
        alpha                           matlab.ui.control.EditField
        cavitylossrate1nsEditFieldLabel  matlab.ui.control.Label
        kappa                           matlab.ui.control.EditField
        carrierlossrate1nsEditFieldLabel  matlab.ui.control.Label
        gamma                           matlab.ui.control.EditField
        couplingABEditFieldLabel        matlab.ui.control.Label
        couplingAB                      matlab.ui.control.EditField
        couplingBAEditFieldLabel        matlab.ui.control.Label
        couplingBA                      matlab.ui.control.EditField
        pumpBEditFieldLabel             matlab.ui.control.Label
        pumpB                           matlab.ui.control.EditField
        pumpALabel                      matlab.ui.control.Label
        pumpA                           matlab.ui.control.EditField
        couplingphaseradEditFieldLabel  matlab.ui.control.Label
        phase                           matlab.ui.control.EditField
        detuningradnsEditFieldLabel     matlab.ui.control.Label
        detuning                        matlab.ui.control.EditField
        alphaX                          matlab.ui.control.CheckBox
        kappaX                          matlab.ui.control.CheckBox
        gammaX                          matlab.ui.control.CheckBox
        couplingABX                     matlab.ui.control.CheckBox
        couplingBAX                     matlab.ui.control.CheckBox
        phaseX                          matlab.ui.control.CheckBox
        detuningX                       matlab.ui.control.CheckBox
        pumpAX                          matlab.ui.control.CheckBox
        pumpBX                          matlab.ui.control.CheckBox
        alphaY                          matlab.ui.control.CheckBox
        kappaY                          matlab.ui.control.CheckBox
        gammaY                          matlab.ui.control.CheckBox
        couplingABY                     matlab.ui.control.CheckBox
        couplingBAY                     matlab.ui.control.CheckBox
        phaseY                          matlab.ui.control.CheckBox
        detuningY                       matlab.ui.control.CheckBox
        pumpAY                          matlab.ui.control.CheckBox
        pumpBY                          matlab.ui.control.CheckBox
        EqualguidesCheckBox             matlab.ui.control.CheckBox
        EqualpumpsCheckBox              matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        ParameterRangePanel             matlab.ui.container.Panel
        xstart                          matlab.ui.control.EditField
        xend                            matlab.ui.control.EditField
        ystart                          matlab.ui.control.EditField
        yend                            matlab.ui.control.EditField
        xlabel                          matlab.ui.control.Label
        ylabel                          matlab.ui.control.Label
        startLabel                      matlab.ui.control.Label
        endLabel                        matlab.ui.control.Label
        GridpointsEditFieldLabel        matlab.ui.control.Label
        Gridpoints                      matlab.ui.control.EditField
        StabilityMapCoupledModeModelLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        RevertButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        isSaved = true  % All changes have been saved
        isData = false  % There is no data loaded or calculated
        data            % Structure for data
        NGrid = 31      % Default grid size
        
    end
    
    methods (Access = private)
        
        function falsifyX(app)
            
            % Ideally, I would like to replace this with a loop accessing
            % an array of pointers (if that is possible)
            
            if (app.alphaX.Value)
                app.alphaX.Value = false;
                app.alpha.Enable = true;
            end
            
            if (app.kappaX.Value)
                app.kappaX.Value = false;
                app.kappa.Enable = true;
            end
            
            if (app.gammaX.Value)
                app.gammaX.Value = false;
                app.gamma.Enable = true;
            end

            if (app.couplingABX.Value)
                app.couplingABX.Value = false;
                app.couplingAB.Enable = true;
            end
            
            if (app.couplingBAX.Value)
                app.couplingBAX.Value = false;
                app.couplingBA.Enable = true;
            end
            
            if (app.phaseX.Value)
                app.phaseX.Value = false;
                app.phase.Enable = true;
            end
            
            if (app.detuningX.Value)
                app.detuningX.Value = false;
                app.detuning.Enable = true;
            end
            
            if (app.pumpAX.Value)
                app.pumpAX.Value = false;
                app.pumpA.Enable = true;
            end
            
            if (app.pumpBX.Value)
                app.pumpBX.Value = false;
                app.pumpB.Enable = true;
            end
            
        end
        
        function falsifyY(app)
            
            % Ideally, I would like to replace this with a loop accessing
            % an array of pointers (if that is possible)
            
            if (app.alphaY.Value)
                app.alphaY.Value = false;
                app.alpha.Enable = true;
            end
            
            if (app.kappaY.Value)
                app.kappaY.Value = false;
                app.kappa.Enable = true;
            end
            
            if (app.gammaY.Value)
                app.gammaY.Value = false;
                app.gamma.Enable = true;
            end

            if (app.couplingABY.Value)
                app.couplingABY.Value = false;
                app.couplingAB.Enable = true;
            end
            
            if (app.couplingBAY.Value)
                app.couplingBAY.Value = false;
                app.couplingBA.Enable = true;
            end
            
            if (app.phaseY.Value)
                app.phaseY.Value = false;
                app.phase.Enable = true;
            end
            
            if (app.detuningY.Value)
                app.detuningY.Value = false;
                app.detuning.Enable = true;
            end
            
            if (app.pumpAY.Value)
                app.pumpAY.Value = false;
                app.pumpA.Enable = true;
            end
            
            if (app.pumpBY.Value)
                app.pumpBY.Value = false;
                app.pumpB.Enable = true;
            end
            
        end
        
        
        function loadData(app)
            
            [file,path] = uigetfile('*.mat');

            if not(isequal(file,0))
                
                app.data = load(fullfile(path,file));
                
                % Check data has correct fields
                if (not(isfield(app.data, 'X')) || not(isfield(app.data, 'Y')) || not(isfield(app.data, 'Z'))) 
                    
                    msgbox(['File ' file ' did not contain valid grid data'], 'Invalid grid data','error');
                    
                    % Erase data
                    app.data = 0;
                    
                else
                    
                    app.isData = true;
                    
                    app.plotData();
 
                end
                
            end
            
            % Hack to bring figure back to front
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
        end
        
        function plotData(app)
            
            if not(app.isData)
                return
            end
            
            % Create colour map
            values = app.data.Z;
            ncols = 11;
            
            maxv = max(values(:));
            minv = min(values(:));
            
            vspan = maxv-minv;
            dv = vspan/(ncols - 1);
            
            sample = minv:dv:maxv;
            neg_mask = (sample <= 0);
            pos_mask = (sample > 0);
            
            c0 = 0.4;
            
            red = (1-c0)*abs(sample.*pos_mask/maxv) + c0*pos_mask;
            green = (1-c0)*abs(sample.*neg_mask/minv) + c0*neg_mask;
            blue = zeros(size(sample));
            
            mymap = transpose([red; green; blue]);
            
            contourf(app.UIAxes, app.data.X, app.data.Y, app.data.Z, 'EdgeColor', 'none')
            colormap(app.UIAxes, mymap)
            colorbar(app.UIAxes)
            app.UIAxes.XLabel.String = '{\it d/a}';
            app.UIAxes.YLabel.String = '{\it P/P_{th}}';
            
  
            % Alternative hack to bring figure back to front
%             figure(app.UIFigure)

            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            
            % Initialize parameters with defaults
            app.alpha.Value = num2str(2);           % Linewidth enhancement factor
            app.kappa.Value = num2str(326.7974);    % Cavity loss rate 1/(2*tau_p) (1/ns)
            app.gamma.Value = num2str(1.0);         % 1/tau_N, where tau_N is the carrier lifetime
            app.couplingAB.Value = num2str(1.0);      % Amplitude of coupling coefficient AB
            app.couplingBA.Value = num2str(1.0);      % Amplitude of coupling coefficient BA
            app.phase.Value = num2str(0.0);      % Phase of coupling coefficient
            app.detuning.Value = num2str(0.0);         % Detuning
            app.pumpA.Value = num2str(10);          % Normalised pump power in guide A
            app.pumpB.Value = num2str(10);          % Normalised pump power in guide B
            
            % Set initial map parameters
%             app.couplingABX.Value = true;
%             app.pumpAY.Value = true;
%             app.xlabel.Text = 'coupling (equal)';
%             app.ylabel.Text = 'pump (equal)';
%             app.xstart.Value = num2str(1);
%             app.xend.Value = num2str(5);
%             app.ystart.Value = num2str(1);
%             app.yend.Value = num2str(10);

            app.xlabel.Text = '?';
            app.ylabel.Text = '?';
            
            % Set equal guides
            app.EqualguidesCheckBox.Value = true;
            app.couplingBA.Enable = false;
            app.couplingBAX.Enable = false;
            app.couplingBAY.Enable = false;

            % Set equal pumps
            app.EqualpumpsCheckBox.Value = true;
            app.pumpB.Enable = false;
            app.pumpBX.Enable = false;
            app.pumpBY.Enable = false;
            
            % Grid points
            app.Gridpoints.Value = num2str(app.NGrid);
           
            
        end

        % Value changed function: kappaX
        function kappaXValueChanged(app, event)
            
            value = app.kappaX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.kappaX.Value = true;
                app.kappa.Enable = false;
                app.xlabel.Text = 'cavity loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.kappaY.Value == true)
                    
                    app.kappaY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.kappa.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: gammaX
        function gammaXValueChanged(app, event)
            
            value = app.gammaX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.gammaX.Value = true;
                app.gamma.Enable = false;
                app.xlabel.Text = 'carrier loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.gammaY.Value == true)
                    
                    app.gammaY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.gamma.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: couplingABX
        function couplingABXValueChanged(app, event)
            
            value = app.couplingABX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.couplingABX.Value = true;
                app.couplingAB.Enable = false;
                
                % Check corresponding Y checkbox
                if (app.couplingABY.Value == true)
                    
                    app.couplingABY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                if (app.EqualguidesCheckBox.Value)
                    
                    app.xlabel.Text = 'coupling (equal)';
                    
                else
                    
                    app.xlabel.Text = 'coupling (AB)';
                    
                end   
                
            else
                
                app.couplingAB.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: couplingBAX
        function couplingBAXValueChanged(app, event)
            
            value = app.couplingBAX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.couplingBAX.Value = true;
                app.couplingBA.Enable = false;
                
                app.xlabel.Text = 'coupling (BA)';
                
                % Check corresponding Y checkbox
                if (app.couplingBAY.Value == true)
                    
                    app.couplingBAY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                
            else
                
                app.couplingBA.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: phaseX
        function phaseXValueChanged(app, event)
            
            value = app.phaseX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.phaseX.Value = true;
                app.phase.Enable = false;
                app.xlabel.Text = 'coupling phase (rad)';
                
                % Check corresponding Y checkbox
                if (app.phaseY.Value == true)
                    
                    app.phaseY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.phase.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: detuningX
        function detuningXValueChanged(app, event)
            
            value = app.detuningX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.detuningX.Value = true;
                app.detuning.Enable = false;
                app.xlabel.Text = 'detuning (rad/ns)';
                
                % Check corresponding Y checkbox
                if (app.detuningY.Value == true)
                    
                    app.detuningY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.detuning.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: pumpAX
        function pumpAXValueChanged(app, event)
            
            value = app.pumpAX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.pumpAX.Value = true;
                app.pumpA.Enable = false;
                
                % Check corresponding Y checkbox
                if (app.pumpAY.Value == true)
                    
                    app.pumpAY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                if (app.EqualpumpsCheckBox.Value)
                    
                    app.xlabel.Text = 'pump (equal)';
                    
                else
                    
                    app.xlabel.Text = 'pump (A)';
                    
                end   
                
            else
                
                app.pumpA.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: pumpBX
        function pumpBXValueChanged(app, event)
            
            value = app.pumpBX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.pumpBX.Value = true;
                app.pumpB.Enable = false;
                
                % Check corresponding Y checkbox
                if (app.pumpBY.Value == true)
                    
                    app.pumpBY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.pumpB.Enable = true;
                
                app.xlabel.Text = '?';
                
            end
            
        end

        % Callback function: LoadButton, LoadMenu
        function LoadButtonPushed(app, event)
            
            app.loadData();
            
        end

        % Value changed function: alphaY
        function alphaYValueChanged(app, event)
            
            value = app.alphaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.alphaY.Value = true;
                app.alpha.Enable = false;
                app.ylabel.Text = 'alpha';
                
                % Check corresponding Y checkbox
                if (app.alphaX.Value == true)
                    
                    app.alphaX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.alpha.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: kappaY
        function kappaYValueChanged(app, event)
            
            value = app.kappaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.kappaY.Value = true;
                app.kappa.Enable = false;
                app.ylabel.Text = 'cavity loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.kappaX.Value == true)
                    
                    app.kappaX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.kappa.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: gammaY
        function gammaYValueChanged(app, event)
            
            value = app.gammaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.gammaY.Value = true;
                app.gamma.Enable = false;
                app.ylabel.Text = 'carrier loss rate (1/ns)';
                
                % Check corresponding Y checkbox
                if (app.gammaX.Value == true)
                    
                    app.gammaX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.gamma.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: couplingABY
        function couplingABYValueChanged(app, event)
            
            value = app.couplingABY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.couplingABY.Value = true;
                app.couplingAB.Enable = false;
                
                if (app.EqualguidesCheckBox.Value)
                    
                    app.couplingBA.Enable = false;
                    
                    app.ylabel.Text = 'coupling (equal)';
                    
                else
                    
                    app.ylabel.Text = 'coupling (AB)';
                    
                end 
                
                % Check corresponding Y checkbox
                if (app.couplingABX.Value == true)
                    
                    app.couplingABX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.couplingAB.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: couplingBAY
        function couplingBAYValueChanged(app, event)
            
            value = app.couplingBAY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.couplingBAY.Value = true;
                app.couplingBA.Enable = false;
                app.ylabel.Text = 'coupling (BA)';
                
                % Check corresponding Y checkbox
                if (app.couplingBAX.Value == true)
                    
                    app.couplingBAX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.couplingBA.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: phaseY
        function phaseYValueChanged(app, event)
            
            value = app.phaseY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.phaseY.Value = true;
                app.phase.Enable = false;
                app.ylabel.Text = 'coupling phase (rad)';
                
                % Check corresponding Y checkbox
                if (app.phaseX.Value == true)
                    
                    app.phaseX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.phase.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: detuningY
        function detuningYValueChanged(app, event)
            
            value = app.detuningY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.detuningY.Value = true;
                app.detuning.Enable = false;
                app.ylabel.Text = 'detuning (rad/ns)';
                
                % Check corresponding Y checkbox
                if (app.detuningX.Value == true)
                    
                    app.detuningX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
            else
                
                app.detuning.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: pumpAY
        function pumpAYValueChanged(app, event)
            
            value = app.pumpAY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.pumpAY.Value = true;
                app.pumpA.Enable = false;
                
                if (app.EqualpumpsCheckBox.Value)
                    
                    app.ylabel.Text = 'pump (equal)';
                    
                else
                    
                    app.ylabel.Text = 'pump (A)';
                    
                end 
                
                % Check corresponding Y checkbox
                if (app.pumpAX.Value == true)
                    
                    app.pumpAX.Value = false;
                    app.xlabel.Text = '?';
                    
                end

            else
                
                app.pumpA.Enable = true;
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: pumpBY
        function pumpBYValueChanged(app, event)
            
            value = app.pumpBY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.pumpBY.Value = true;
                app.pumpB.Enable = false;
                app.ylabel.Text = 'pump (B)';
                
                % Check corresponding Y checkbox
                if (app.pumpBX.Value == true)
                    
                    app.pumpBX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
                
            else
                
                app.pumpB.Enable = true; 
                app.ylabel.Text = '?';
                
            end
            
        end

        % Value changed function: alpha
        function alphaValueChanged(app, event)
            
            value = app.alpha.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: kappa
        function kappaValueChanged(app, event)
            
            value = app.kappa.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
        end

        % Value changed function: gamma
        function gammaValueChanged(app, event)
            
            value = app.gamma.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
        end

        % Value changed function: couplingAB
        function couplingABValueChanged(app, event)
            
            value = app.couplingAB.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
            elseif (app.EqualguidesCheckBox.Value)
                
                app.couplingBA.Value = value;     
                
            end
            
        end

        % Value changed function: couplingBA
        function couplingBAValueChanged(app, event)
            
            value = app.couplingBA.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: pumpB
        function pumpBValueChanged(app, event)
            
            value = app.pumpB.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: pumpA
        function pumpAValueChanged(app, event)
            
            value = app.pumpA.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
            elseif (app.EqualpumpsCheckBox.Value)
                
                app.pumpB.Value = value;
                
            end
            
        end

        % Value changed function: phase
        function phaseValueChanged(app, event)
            
            value = app.phase.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: detuning
        function detuningValueChanged(app, event)
            
            value = app.detuning.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: alphaX
        function alphaXValueChanged(app, event)
            
            value = app.alphaX.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyX();
                app.alphaX.Value = true;
                app.alpha.Enable = false;
                app.xlabel.Text = 'alpha';
                
                % Check corresponding Y checkbox
                if (app.alphaY.Value == true)
                    
                    app.alphaY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
            else
                
                app.alpha.Enable = true;
                app.xlabel.Text = '?';
                
            end
            
        end

        % Value changed function: xstart
        function xstartValueChanged(app, event)
            
            value = app.xstart.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: xend
        function xendValueChanged(app, event)
            
            value = app.xend.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: ystart
        function ystartValueChanged(app, event)
            
            value = app.ystart.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: yend
        function yendValueChanged(app, event)
            
            value = app.yend.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                
            end
            
        end

        % Value changed function: Gridpoints
        function GridpointsValueChanged(app, event)
            
            value = app.Gridpoints.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
            else
                
                N = floor(str2double(value));
                
                if (N < 2)
                    
                    msgbox('Number of grid points must be an integer greater than 1')
                    
                else
                    
                    app.Gridpoints.Value = num2str(N);
                    
                end               
                
            end
            
        end

        % Value changed function: EqualguidesCheckBox
        function EqualguidesCheckBoxValueChanged(app, event)
            
            value = app.EqualguidesCheckBox.Value;
            
            if (value)
                
                % Enforce equal guides
                app.couplingBA.Value = app.couplingAB.Value;
                
                % Check for checked guides
                if (app.couplingBAX.Value)
                    
                    app.couplingBAX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
                if (app.couplingBAY.Value)
                    
                    app.couplingBAY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                app.couplingBA.Enable = false;
                app.couplingBAX.Enable = false;
                app.couplingBAY.Enable = false;
                      
            else
                
                % Allow unequal guides
                app.couplingBA.Enable = true;
                app.couplingBAX.Enable = true;
                app.couplingBAY.Enable = true;
                
            end
            
        end

        % Value changed function: EqualpumpsCheckBox
        function EqualpumpsCheckBoxValueChanged(app, event)
            
            value = app.EqualpumpsCheckBox.Value;
            
            if (value)
                
                % Enforce equal guides
                app.pumpB.Value = app.pumpA.Value;
                
                % Check for checked guides
                if (app.pumpBX.Value)
                    
                    app.pumpBX.Value = false;
                    app.xlabel.Text = '?';
                    
                end
                
                if (app.pumpBY.Value)
                    
                    app.pumpBY.Value = false;
                    app.ylabel.Text = '?';
                    
                end
                
                app.pumpB.Enable = false;
                app.pumpBX.Enable = false;
                app.pumpBY.Enable = false;
                      
            else
                
                % Allow unequal guides
                app.pumpB.Enable = true;
                app.pumpBX.Enable = true;
                app.pumpBY.Enable = true;
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.LoadMenu = uimenu(app.DataMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadMenu.Accelerator = 'l';
            app.LoadMenu.Text = 'Load';
            app.RevertMenu = uimenu(app.DataMenu);
            app.RevertMenu.Accelerator = 'z';
            app.RevertMenu.Text = 'Revert';
            app.SaveMenu = uimenu(app.DataMenu);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Accelerator = 'S';
            app.SaveMenu.Text = 'Save';
            app.RunMenu = uimenu(app.UIFigure);
            app.RunMenu.Text = 'Run';
            app.RunMenu_2 = uimenu(app.RunMenu);
            app.RunMenu_2.Accelerator = 'r';
            app.RunMenu_2.Text = 'Run';
            app.ParametersforRateEquationsPanel = uipanel(app.UIFigure);
            app.ParametersforRateEquationsPanel.Title = 'Parameters for Rate Equations';
            app.NameLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.NameLabel.Text = {'Name'; ''};
            app.ValueLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.ValueLabel.Text = {'Value'; ''};
            app.X_label = uilabel(app.ParametersforRateEquationsPanel);
            app.X_label.Text = {'X'; ''};
            app.Y_label = uilabel(app.ParametersforRateEquationsPanel);
            app.Y_label.Text = {'Y'; ''};
            app.alphaEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alpha = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.alpha.ValueChangedFcn = createCallbackFcn(app, @alphaValueChanged, true);
            app.alpha.Tooltip = {'Linewidth enhancement factor'};
            app.cavitylossrate1nsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.cavitylossrate1nsEditFieldLabel.Text = 'cavity loss rate (1/ns)';
            app.kappa = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.kappa.ValueChangedFcn = createCallbackFcn(app, @kappaValueChanged, true);
            app.kappa.Tooltip = {'1/(2*tp), where tp is the photon lifetime'};
            app.carrierlossrate1nsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.carrierlossrate1nsEditFieldLabel.Text = 'carrier loss rate (1/ns)';
            app.gamma = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.gamma.ValueChangedFcn = createCallbackFcn(app, @gammaValueChanged, true);
            app.gamma.Tooltip = {'1/tN, were tN is the carrier lifetime'};
            app.couplingABEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingABEditFieldLabel.Text = 'coupling (AB)';
            app.couplingAB = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.couplingAB.ValueChangedFcn = createCallbackFcn(app, @couplingABValueChanged, true);
            app.couplingAB.Tooltip = {'amplitude of coupling coefficient (AB)'};
            app.couplingBAEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingBAEditFieldLabel.Text = 'coupling (BA)';
            app.couplingBA = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.couplingBA.ValueChangedFcn = createCallbackFcn(app, @couplingBAValueChanged, true);
            app.couplingBA.Tooltip = {'amplitude of coupling coefficient (BA)'};
            app.pumpBEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.pumpBEditFieldLabel.Text = 'pump (B)';
            app.pumpB = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.pumpB.ValueChangedFcn = createCallbackFcn(app, @pumpBValueChanged, true);
            app.pumpB.Tooltip = {'normalised pumping in guide B'};
            app.pumpALabel = uilabel(app.ParametersforRateEquationsPanel);
            app.pumpALabel.Text = 'pump (A)';
            app.pumpA = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.pumpA.ValueChangedFcn = createCallbackFcn(app, @pumpAValueChanged, true);
            app.pumpA.Tooltip = {'normalised pumping in guide A'};
            app.couplingphaseradEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingphaseradEditFieldLabel.Text = 'coupling phase (rad)';
            app.phase = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.phase.ValueChangedFcn = createCallbackFcn(app, @phaseValueChanged, true);
            app.phase.Tooltip = {'phase of coupling coefficient'};
            app.detuningradnsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.detuningradnsEditFieldLabel.Text = 'detuning (rad/ns)';
            app.detuning = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.detuning.ValueChangedFcn = createCallbackFcn(app, @detuningValueChanged, true);
            app.detuning.Tooltip = {'laser detuning in rad/ns - N.B. NOT GHz'};
            app.alphaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.alphaX.ValueChangedFcn = createCallbackFcn(app, @alphaXValueChanged, true);
            app.alphaX.Tooltip = {'select this parameter to vary along x axis'};
            app.alphaX.Text = '';
            app.kappaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.kappaX.ValueChangedFcn = createCallbackFcn(app, @kappaXValueChanged, true);
            app.kappaX.Tooltip = {'select this parameter to vary along x axis'};
            app.kappaX.Text = '';
            app.gammaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.gammaX.ValueChangedFcn = createCallbackFcn(app, @gammaXValueChanged, true);
            app.gammaX.Tooltip = {'select this parameter to vary along x axis'};
            app.gammaX.Text = '';
            app.couplingABX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingABX.ValueChangedFcn = createCallbackFcn(app, @couplingABXValueChanged, true);
            app.couplingABX.Tooltip = {'select this parameter to vary along x axis'};
            app.couplingABX.Text = '';
            app.couplingBAX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingBAX.ValueChangedFcn = createCallbackFcn(app, @couplingBAXValueChanged, true);
            app.couplingBAX.Tooltip = {'select this parameter to vary along x axis'};
            app.couplingBAX.Text = '';
            app.phaseX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.phaseX.ValueChangedFcn = createCallbackFcn(app, @phaseXValueChanged, true);
            app.phaseX.Tooltip = {'select this parameter to vary along x axis'};
            app.phaseX.Text = '';
            app.detuningX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.detuningX.ValueChangedFcn = createCallbackFcn(app, @detuningXValueChanged, true);
            app.detuningX.Tooltip = {'select this parameter to vary along x axis'};
            app.detuningX.Text = '';
            app.pumpAX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpAX.ValueChangedFcn = createCallbackFcn(app, @pumpAXValueChanged, true);
            app.pumpAX.Tooltip = {'select this parameter to vary along x axis'};
            app.pumpAX.Text = '';
            app.pumpBX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpBX.ValueChangedFcn = createCallbackFcn(app, @pumpBXValueChanged, true);
            app.pumpBX.Tooltip = {'select this parameter to vary along x axis'};
            app.pumpBX.Text = '';
            app.alphaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.alphaY.ValueChangedFcn = createCallbackFcn(app, @alphaYValueChanged, true);
            app.alphaY.Tooltip = {'select this parameter to vary along y axis'};
            app.alphaY.Text = '';
            app.kappaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.kappaY.ValueChangedFcn = createCallbackFcn(app, @kappaYValueChanged, true);
            app.kappaY.Tooltip = {'select this parameter to vary along y axis'};
            app.kappaY.Text = '';
            app.gammaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.gammaY.ValueChangedFcn = createCallbackFcn(app, @gammaYValueChanged, true);
            app.gammaY.Tooltip = {'select this parameter to vary along y axis'};
            app.gammaY.Text = '';
            app.couplingABY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingABY.ValueChangedFcn = createCallbackFcn(app, @couplingABYValueChanged, true);
            app.couplingABY.Tooltip = {'select this parameter to vary along y axis'};
            app.couplingABY.Text = '';
            app.couplingBAY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingBAY.ValueChangedFcn = createCallbackFcn(app, @couplingBAYValueChanged, true);
            app.couplingBAY.Tooltip = {'select this parameter to vary along y axis'};
            app.couplingBAY.Text = '';
            app.phaseY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.phaseY.ValueChangedFcn = createCallbackFcn(app, @phaseYValueChanged, true);
            app.phaseY.Tooltip = {'select this parameter to vary along y axis'};
            app.phaseY.Text = '';
            app.detuningY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.detuningY.ValueChangedFcn = createCallbackFcn(app, @detuningYValueChanged, true);
            app.detuningY.Tooltip = {'select this parameter to vary along y axis'};
            app.detuningY.Text = '';
            app.pumpAY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpAY.ValueChangedFcn = createCallbackFcn(app, @pumpAYValueChanged, true);
            app.pumpAY.Tooltip = {'select this parameter to vary along y axis'};
            app.pumpAY.Text = '';
            app.pumpBY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpBY.ValueChangedFcn = createCallbackFcn(app, @pumpBYValueChanged, true);
            app.pumpBY.Tooltip = {'select this parameter to vary along y axis'};
            app.pumpBY.Text = '';
            app.EqualguidesCheckBox = uicheckbox(app.ParametersforRateEquationsPanel);
            app.EqualguidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EqualguidesCheckBoxValueChanged, true);
            app.EqualguidesCheckBox.Tooltip = {'When checked, coupling (AB) will be varied with coupling (BA)'};
            app.EqualguidesCheckBox.Text = 'Equal guides';
            app.EqualpumpsCheckBox = uicheckbox(app.ParametersforRateEquationsPanel);
            app.EqualpumpsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EqualpumpsCheckBoxValueChanged, true);
            app.EqualpumpsCheckBox.Tooltip = {'When checked, pump (A) will be varied with pump (B)'};
            app.EqualpumpsCheckBox.Text = 'Equal pumps';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ParameterRangePanel = uipanel(app.UIFigure);
            app.ParameterRangePanel.Title = 'Parameter Range';
            app.xstart = uieditfield(app.ParameterRangePanel, 'text');
            app.xstart.ValueChangedFcn = createCallbackFcn(app, @xstartValueChanged, true);
            app.xend = uieditfield(app.ParameterRangePanel, 'text');
            app.xend.ValueChangedFcn = createCallbackFcn(app, @xendValueChanged, true);
            app.ystart = uieditfield(app.ParameterRangePanel, 'text');
            app.ystart.ValueChangedFcn = createCallbackFcn(app, @ystartValueChanged, true);
            app.yend = uieditfield(app.ParameterRangePanel, 'text');
            app.yend.ValueChangedFcn = createCallbackFcn(app, @yendValueChanged, true);
            app.xlabel = uilabel(app.ParameterRangePanel);
            app.xlabel.Text = 'x label';
            app.ylabel = uilabel(app.ParameterRangePanel);
            app.ylabel.Text = 'y label';
            app.startLabel = uilabel(app.ParameterRangePanel);
            app.startLabel.Text = 'start';
            app.endLabel = uilabel(app.ParameterRangePanel);
            app.endLabel.Text = 'end';
            app.GridpointsEditFieldLabel = uilabel(app.ParameterRangePanel);
            app.GridpointsEditFieldLabel.Text = 'Grid points';
            app.Gridpoints = uieditfield(app.ParameterRangePanel, 'text');
            app.Gridpoints.ValueChangedFcn = createCallbackFcn(app, @GridpointsValueChanged, true);
            app.Gridpoints.Tooltip = {'number of grid points to plot on each axis'};
            app.StabilityMapCoupledModeModelLabel = uilabel(app.UIFigure);
            app.StabilityMapCoupledModeModelLabel.Text = 'Stability Map - Coupled Mode Model';
            app.Panel = uipanel(app.UIFigure);
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load data from a file'};
            app.LoadButton.Text = 'Load';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.Tooltip = {'Generate the stability map'};
            app.RunButton.Text = 'Run';
            app.SaveDataButton = uibutton(app.Panel, 'push');
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Tooltip = {'Save parameters and calculated data'};
            app.SaveDataButton.Text = 'Save Data';
            app.RevertButton = uibutton(app.Panel, 'push');
            app.RevertButton.Tooltip = {'Revert parameters to last run / saved or loaded'};
            app.RevertButton.Text = 'Revert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StabilityMap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end